classdef Electrode_Analyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElectrodeAnalyser             matlab.ui.Figure
        Tab                           matlab.ui.container.TabGroup
        ImpedanceSpectrometryTab      matlab.ui.container.Tab
        SelectedfilesTextAreaLabel    matlab.ui.control.Label
        SelectedfilesTextArea         matlab.ui.control.TextArea
        BrowseButtonEIS               matlab.ui.control.Button
        UIModulus                     matlab.ui.control.UIAxes
        UIPhase                       matlab.ui.control.UIAxes
        UIImpedance1khz               matlab.ui.control.Table
        ProcessButtonEIS              matlab.ui.control.Button
        CyclicVoltametryTab           matlab.ui.container.Tab
        BrowseButtonCV                matlab.ui.control.Button
        figureCV                      matlab.ui.control.UIAxes
        figureCV2                     matlab.ui.control.UIAxes
        tableCSC                      matlab.ui.control.Table
        ProcessButtonCV               matlab.ui.control.Button
        SelectedfilesTextArea_2Label  matlab.ui.control.Label
        SelectedfilesTextAreaCV       matlab.ui.control.TextArea
        VoltageTransientTab           matlab.ui.container.Tab
    end


    properties (Access = public)
        importedFilesEIS % string or cell array containing the .DTA files to process
        loopSizeEIS % number of imported files
        fileNameArrayEIS
        implantIDArrayEIS
        electrodeIDArrayEIS
        frequency
        zModulusArray
        zPhaseArray
        implantModulusPlot
        implantPhasePlot    
    end

    methods (Access = private)
        
        function importFrequency = importFreq(app,filename, startRow, endRow)
            %IMPORTFILE Import numeric data from a text file as column vectors.
            %   FREQUENCY = IMPORTFILE(FILENAME) Reads data from text file FILENAME for
            %   the default selection.
            %
            %   FREQUENCY = IMPORTFILE(FILENAME, STARTROW, ENDROW) Reads data from rows
            %   STARTROW through ENDROW of text file FILENAME.
            %
            % Example:
            %   frequency = importfile('170914_CI001_2C_EIS.DTA',80, 130);
            %
            %    See also TEXTSCAN.
            
            % Auto-generated by MATLAB on 2017/09/15 16:07:58
            
            %% Initialize variables.
            delimiter = '\t';
            if nargin<=2
                startRow = 80;
                endRow = inf;
            end
            
            %% Format for each line of text:
            %   column4: text (%s)
            % For more information, see the TEXTSCAN documentation.
            formatSpec = '%*s%*s%*s%s%*s%*s%*s%*s%*s%*s%*s%*s%[^\n\r]';
            
            %% Open the text file.
            fileID = fopen(filename,'r');
            
            %% Read columns of data according to the format.
            % This call is based on the structure of the file used to generate this
            % code. If an error occurs for a different file, try regenerating the code
            % from the Import Tool.
            dataArray = textscan(fileID, formatSpec, endRow(1)-startRow(1)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(1)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            for block=2:length(startRow)
                frewind(fileID);
                dataArrayBlock = textscan(fileID, formatSpec, endRow(block)-startRow(block)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(block)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
                dataArray{1} = [dataArray{1};dataArrayBlock{1}];
            end
            
            %% Close the text file.
            fclose(fileID);
            
            %% Post processing for unimportable data.
            % No unimportable data rules were applied during the import, so no post
            % processing code is included. To generate code which works for
            % unimportable data, select unimportable cells in a file and regenerate the
            % script.
            
            %% Allocate imported array to column variable names
            importFrequency = dataArray{:, 1};
        end
        
        function importZMod = importMod(app,filename, startRow, endRow)
            %IMPORTFILE Import numeric data from a text file as column vectors.
            %   VARNAME8 = IMPORTFILE(FILENAME) Reads data from text file FILENAME for
            %   the default selection.
            %
            %   VARNAME8 = IMPORTFILE(FILENAME, STARTROW, ENDROW) Reads data from rows
            %   STARTROW through ENDROW of text file FILENAME.
            %
            % Example:
            %   VarName8 = importfile('170914_CI001_2C_EIS.DTA',80, 130);
            %
            %    See also TEXTSCAN.
            
            % Auto-generated by MATLAB on 2017/09/15 16:09:28
            
            %% Initialize variables.
            delimiter = '\t';
            if nargin<=2
                startRow = 80;
                endRow = inf;
            end
            
            %% Format for each line of text:
            %   column8: double (%f)
            % For more information, see the TEXTSCAN documentation.
            formatSpec = '%*s%*s%*s%*s%*s%*s%*s%f%*s%*s%*s%*s%[^\n\r]';
            
            %% Open the text file.
            fileID = fopen(filename,'r');
            
            %% Read columns of data according to the format.
            % This call is based on the structure of the file used to generate this
            % code. If an error occurs for a different file, try regenerating the code
            % from the Import Tool.
            dataArray = textscan(fileID, formatSpec, endRow(1)-startRow(1)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(1)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            for block=2:length(startRow)
                frewind(fileID);
                dataArrayBlock = textscan(fileID, formatSpec, endRow(block)-startRow(block)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(block)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
                dataArray{1} = [dataArray{1};dataArrayBlock{1}];
            end
            
            %% Close the text file.
            fclose(fileID);
            
            %% Post processing for unimportable data.
            % No unimportable data rules were applied during the import, so no post
            % processing code is included. To generate code which works for
            % unimportable data, select unimportable cells in a file and regenerate the
            % script.
            
            %% Allocate imported array to column variable names
            importZMod = dataArray{:, 1};
        end

        function importZPhz = importPhz(app,filename, startRow, endRow)
            %IMPORTFILE Import numeric data from a text file as column vectors.
            %   VARNAME9 = IMPORTFILE(FILENAME) Reads data from text file FILENAME for
            %   the default selection.
            %
            %   VARNAME9 = IMPORTFILE(FILENAME, STARTROW, ENDROW) Reads data from rows
            %   STARTROW through ENDROW of text file FILENAME.
            %
            % Example:
            %   VarName9 = importfile('170914_CI001_2C_EIS.DTA',80, 130);
            %
            %    See also TEXTSCAN.
            
            % Auto-generated by MATLAB on 2017/09/15 16:10:36
            
            %% Initialize variables.
            delimiter = '\t';
            if nargin<=2
                startRow = 80;
                endRow = inf;
            end
            
            %% Format for each line of text:
            %   column9: double (%f)
            % For more information, see the TEXTSCAN documentation.
            formatSpec = '%*s%*s%*s%*s%*s%*s%*s%*s%f%*s%*s%*s%[^\n\r]';
            
            %% Open the text file.
            fileID = fopen(filename,'r');
            
            %% Read columns of data according to the format.
            % This call is based on the structure of the file used to generate this
            % code. If an error occurs for a different file, try regenerating the code
            % from the Import Tool.
            dataArray = textscan(fileID, formatSpec, endRow(1)-startRow(1)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(1)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            for block=2:length(startRow)
                frewind(fileID);
                dataArrayBlock = textscan(fileID, formatSpec, endRow(block)-startRow(block)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(block)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
                dataArray{1} = [dataArray{1};dataArrayBlock{1}];
            end
            
            %% Close the text file.
            fclose(fileID);
            
            %% Post processing for unimportable data.
            % No unimportable data rules were applied during the import, so no post
            % processing code is included. To generate code which works for
            % unimportable data, select unimportable cells in a file and regenerate the
            % script.
            
            %% Allocate imported array to column variable names
            importZPhz = dataArray{:, 1};
        end  

    end


    methods (Access = private)

        % Button pushed function: BrowseButtonEIS
        function BrowseButtonEISPushed(app, event)
            % Load the names of the selected files
            app.ElectrodeAnalyser.Visible = 'off';
            disp('> Select the .DTA files...')
            [app.importedFilesEIS, pathName] = uigetfile({'*.DTA'},'MultiSelect','on');
            drawnow;
            app.ElectrodeAnalyser.Visible = 'on';
            app.fileNameArrayEIS=[];
            app.implantIDArrayEIS=[];
            app.electrodeIDArrayEIS=[];
            app.frequency=[];
            app.zModulusArray=[];
            app.zPhaseArray=[];
            if iscell(app.importedFilesEIS)
                app.loopSizeEIS = size(app.importedFilesEIS,2); %getfile has returned >1 files sorted in a cell array
            else
                app.loopSizeEIS = 1; %getfile has return 1 file, fileNameArrayEIS = 1xstringLength
            end
            for i=1:1:app.loopSizeEIS
                if iscell(app.importedFilesEIS)
                    app.fileNameArrayEIS = cat(1,app.fileNameArrayEIS, strjoin(app.importedFilesEIS(i))); %strjoin converts cell type into string
                else
                    app.fileNameArrayEIS = [app.importedFilesEIS]; %getfile has only returned 1 file whose name is already a string
                end
            end
            app.SelectedfilesTextArea.Value = app.importedFilesEIS;
            app.ProcessButtonEIS.Enable = 'on';
        end

        % Button pushed function: ProcessButtonEIS
        function ProcessButtonEISPushed(app, event)
            
            %app.ElectrodeAnalyser.Visible = 'off';
            for i=1:1:app.loopSizeEIS
                
                % Extract the implant and electrode ID from the file name
                fileName = app.fileNameArrayEIS(i,:);
                indexOfImplantID = strfind(fileName,'W');
                implantID = fileName(indexOfImplantID:indexOfImplantID+9);
                app.implantIDArrayEIS = [app.implantIDArrayEIS; implantID];
                electrodeID = fileName(indexOfImplantID+11:indexOfImplantID+12);
                app.electrodeIDArrayEIS = [app.electrodeIDArrayEIS; electrodeID];
                fprintf('> Processing electrode %s_%s...\n',implantID,electrodeID);
                
                % Import data from the DTA files
                disp('> Importing data')
                app.frequency = str2double(importFreq(app,fileName)); %frequency is stored as a string in data file
                zModulus = importMod(app,fileName);
                app.zModulusArray = [app.zModulusArray zModulus];
                zPhase = importPhz(app,fileName);
                app.zPhaseArray = [app.zPhaseArray zPhase];
                
                % Save impendance at 1kHz
                disp('> Populating .txt file')
                if i == 1
                    fid = fopen(strcat(app.implantIDArrayEIS(1,:),'_impedance_1kHz','.txt'),'wt+'); %create txt file
                    fprintf(fid,'\t \t Frequency [Hz]\t Impedance [kOhm]\n'); %init txt layout
                    fprintf(fid, '%s_%s\t %f.3\t %f.2\n',implantID,electrodeID,app.frequency(app.frequency==998.2640),zModulus(app.frequency==998.2640)./10^3);
                    app.UIImpedance1khz.Data = {electrodeID,app.frequency(app.frequency==998.2640),zModulus(app.frequency==998.2640)./10^3};
                else
                    fid = fopen(strcat(app.implantIDArrayEIS(1,:),'_impedance_1kHz','.txt'),'at+');
                    fprintf(fid, '%s_%s\t %f.3\t %f.2\n',implantID,electrodeID,app.frequency(app.frequency==998.2640),zModulus(app.frequency==998.2640));
                    oldData = app.UIImpedance1khz.Data;
                    newData = cat(1,oldData, {electrodeID,app.frequency(app.frequency==998.2640),zModulus(app.frequency==998.2640)./10^3});
                    app.UIImpedance1khz.Data = newData;
                end
                
                % Generate Bode Plot
                disp('> Generating individual Bode plot')
                warning('off','MATLAB:print:FigureTooLargeForPage');
                titleModulusPlot = replace(strcat('Impedance Modulus_',implantID),'_',' '); %workaround because strcat ignores trailing white space
                titleModulusPlotFile = strcat(implantID,'_',electrodeID,'_impedance_modulus','.pdf');
                modulusPlot = figure('visible','off');
                semilogx(app.frequency,zModulus./10^3,'LineWidth',2.5)
                grid on
                legend(electrodeID)
                xlabel('Frequency [Hz]')
                ylabel('Modulus [k\Omega]')
                title(titleModulusPlot)
                modulusPlot = gcf;
                modulusPlot.PaperPositionMode = 'auto';
                modulusPlot_pos = modulusPlot.PaperPosition;
                modulusPlot.PaperSize = [modulusPlot_pos(3) modulusPlot_pos(4)];
                saveas(gcf,titleModulusPlotFile)
                
                titlePhasePlot = replace(strcat('Impedance Phase_',implantID),'_',' '); %workaround because strcat ignores trailing white space
                titlePhasePlotFile = strcat(implantID,'_',electrodeID,'_impedance_phase','.pdf');
                phasePlot = figure('visible','off');
                semilogx(app.frequency,zPhase,'LineWidth',2.5)
                grid on
                legend(electrodeID)
                xlabel('Frequency [Hz]')
                ylabel('Phase [deg]')
                title(titlePhasePlot)
                phasePlot = gcf;
                phasePlot.PaperPositionMode = 'auto';
                phasePlot_pos = phasePlot.PaperPosition;
                phasePlot.PaperSize = [phasePlot_pos(3) phasePlot_pos(4)];
                saveas(gcf,titlePhasePlotFile)
            
            end
            fclose(fid);
            
            %% GENERATE BODE PLOT FOR ENTIRE IMPLANT
            disp('> Generating full Bode plot')
            app.implantModulusPlot = figure('visible','off');
            grid on
            for i=1:1:app.loopSizeEIS
                semilogx(app.UIModulus,app.frequency,10^-3 * app.zModulusArray(:,i),'LineWidth',2.5)
                hold(app.UIModulus,'on')
                semilogx(app.frequency,app.zModulusArray(:,i)./10^3,'LineWidth',2.5)
                hold on
            end
            hold(app.UIModulus,'off')
            % UIaxes
            legend(app.UIModulus,app.electrodeIDArrayEIS)
            xlabel(app.UIModulus,'Frequency [Hz]')
            ylabel(app.UIModulus,'Modulus [k\Omega]')
            title(app.UIModulus,replace(strcat('Impedance Modulus_',app.implantIDArrayEIS(1,:)),'_',' '))
            %Saved figure
            grid on
            legend(app.electrodeIDArrayEIS)
            xlabel('Frequency [Hz]')
            ylabel('Modulus [k\Omega]')
            title(replace(strcat('Impedance Modulus_',app.implantIDArrayEIS(1,:)),'_',' '))
            app.implantModulusPlot.PaperPositionMode = 'auto';
            implantModulusPlot_pos = app.implantModulusPlot.PaperPosition;
            app.implantModulusPlot.PaperSize = [implantModulusPlot_pos(3) implantModulusPlot_pos(4)];
            titleImplantModulusPlotFile = strcat(app.implantIDArrayEIS(1,:),'_full_impedance_modulus','.pdf');
            saveas(gcf,titleImplantModulusPlotFile)
            
            app.implantPhasePlot = figure('visible','off');
            grid on
            for i=1:1:app.loopSizeEIS
                semilogx(app.UIPhase,app.frequency,app.zPhaseArray(:,i),'LineWidth',2.5)
                hold(app.UIPhase, 'on')
                semilogx(app.frequency,app.zPhaseArray(:,i),'LineWidth',2.5)
                hold on
            end
            hold(app.UIPhase,'off')
            % UIaxes
            legend(app.UIPhase,app.electrodeIDArrayEIS)
            xlabel(app.UIPhase,'Frequency [Hz]')
            ylabel(app.UIPhase,'Phase [deg]')
            title(app.UIPhase,replace(strcat('Impedance Phase_',app.implantIDArrayEIS(1,:)),'_',' '))
            %Saved figure
            grid on
            legend(app.electrodeIDArrayEIS)
            xlabel('Frequency [Hz]')
            ylabel('Phase [deg]')
            title(replace(strcat('Impedance Phase_',app.implantIDArrayEIS(1,:)),'_',' '))
            app.implantPhasePlot.PaperPositionMode = 'auto';
            implantPhasePlot_pos = app.implantPhasePlot.PaperPosition;
            app.implantPhasePlot.PaperSize = [implantPhasePlot_pos(3) implantPhasePlot_pos(4)];
            titleImplantPhasePlotFile = strcat(app.implantIDArrayEIS(1,:),'_full_impedance_phase','.pdf');
            saveas(gcf,titleImplantPhasePlotFile)
            
            % Generate xlsx file
            disp('> Populating xlsx file')
            warning('off','MATLAB:xlswrite:AddSheet');
            xlsxFile = strcat(app.implantIDArrayEIS(1,:),'.xlsx');
            for i=1:1:app.loopSizeEIS
                dataTable = table(app.frequency, app.zModulusArray(:,i), app.zPhaseArray(:,i),'VariableNames',{'Frequency' 'Modulus' 'Phase'});
                writetable(dataTable, xlsxFile, 'Sheet',app.electrodeIDArrayEIS(i,:));
            end
            
            disp('> Done.')
            % UI refresh
            app.ProcessButtonEIS.Enable = 'off';
            drawnow;
            app.ElectrodeAnalyser.Visible = 'off';
            app.ElectrodeAnalyser.Visible = 'on';
        end

        % Button pushed function: BrowseButtonCV
        function BrowseButtonCVPushed(app, event)
            % Load the names of the selected files
            app.ElectrodeAnalyser.Visible = 'off';
            disp('> Select the .DTA files...')
            [app.importedFilesEIS, pathName] = uigetfile({'*.DTA'},'MultiSelect','on');
            drawnow;
            app.ElectrodeAnalyser.Visible = 'on';
            app.fileNameArrayEIS=[];
            app.implantIDArrayEIS=[];
            app.electrodeIDArrayEIS=[];
            app.frequency=[];
            app.zModulusArray=[];
            app.zPhaseArray=[];
            if iscell(app.importedFilesEIS)
                app.loopSizeEIS = size(app.importedFilesEIS,2); %getfile has returned >1 files sorted in a cell array
            else
                app.loopSizeEIS = 1; %getfile has return 1 file, fileNameArrayEIS = 1xstringLength
            end
            for i=1:1:app.loopSizeEIS
                if iscell(app.importedFilesEIS)
                    app.fileNameArrayEIS = cat(1,app.fileNameArrayEIS, strjoin(app.importedFilesEIS(i))); %strjoin converts cell type into string
                else
                    app.fileNameArrayEIS = [app.importedFilesEIS]; %getfile has only returned 1 file whose name is already a string
                end
            end
            app.SelectedfilesTextArea.Value = app.importedFilesEIS;
            app.ProcessButtonEIS.Enable = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElectrodeAnalyser = uifigure;
            app.ElectrodeAnalyser.Name = 'ElectrodeAnalyser';
            app.Tab = uitabgroup(app.ElectrodeAnalyser);
            app.ImpedanceSpectrometryTab = uitab(app.Tab);
            app.ImpedanceSpectrometryTab.Title = 'Impedance Spectrometry';
            app.SelectedfilesTextAreaLabel = uilabel(app.ImpedanceSpectrometryTab);
            app.SelectedfilesTextAreaLabel.Text = 'Selected files:';
            app.SelectedfilesTextArea = uitextarea(app.ImpedanceSpectrometryTab);
            app.SelectedfilesTextArea.Editable = 'off';
            app.BrowseButtonEIS = uibutton(app.ImpedanceSpectrometryTab, 'push');
            app.BrowseButtonEIS.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonEISPushed, true);
            app.BrowseButtonEIS.Text = 'Browse...';
            app.UIModulus = uiaxes(app.ImpedanceSpectrometryTab);
            title(app.UIModulus, 'Impedance Modulus')
            xlabel(app.UIModulus, 'Frequency [Hz]')
            ylabel(app.UIModulus, 'Modulus [kOhm]')
            app.UIPhase = uiaxes(app.ImpedanceSpectrometryTab);
            title(app.UIPhase, 'Phase')
            xlabel(app.UIPhase, 'Frequency [Hz]')
            ylabel(app.UIPhase, 'Phase [deg]')
            app.UIImpedance1khz = uitable(app.ImpedanceSpectrometryTab);
            app.UIImpedance1khz.ColumnName = {'Electrode ID'; 'Frequency [Hz]'; 'Impedance [kOhm]'};
            app.UIImpedance1khz.RowName = {};
            app.ProcessButtonEIS = uibutton(app.ImpedanceSpectrometryTab, 'push');
            app.ProcessButtonEIS.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonEISPushed, true);
            app.ProcessButtonEIS.Enable = 'off';
            app.ProcessButtonEIS.Text = 'Process';
            app.CyclicVoltametryTab = uitab(app.Tab);
            app.CyclicVoltametryTab.Title = 'Cyclic Voltametry';
            app.BrowseButtonCV = uibutton(app.CyclicVoltametryTab, 'push');
            app.BrowseButtonCV.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonCVPushed, true);
            app.BrowseButtonCV.Text = 'Browse...';
            app.figureCV = uiaxes(app.CyclicVoltametryTab);
            title(app.figureCV, 'Impedance Modulus')
            xlabel(app.figureCV, 'Frequency [Hz]')
            ylabel(app.figureCV, 'Modulus [kOhm]')
            app.figureCV2 = uiaxes(app.CyclicVoltametryTab);
            title(app.figureCV2, 'Phase')
            xlabel(app.figureCV2, 'Frequency [Hz]')
            ylabel(app.figureCV2, 'Phase [deg]')
            app.tableCSC = uitable(app.CyclicVoltametryTab);
            app.tableCSC.ColumnName = {'Electrode ID'; 'Frequency [Hz]'; 'Impedance [kOhm]'};
            app.tableCSC.RowName = {};
            app.ProcessButtonCV = uibutton(app.CyclicVoltametryTab, 'push');
            app.ProcessButtonCV.Enable = 'off';
            app.ProcessButtonCV.Text = 'Process';
            app.SelectedfilesTextArea_2Label = uilabel(app.CyclicVoltametryTab);
            app.SelectedfilesTextArea_2Label.Text = 'Selected files:';
            app.SelectedfilesTextAreaCV = uitextarea(app.CyclicVoltametryTab);
            app.SelectedfilesTextAreaCV.Editable = 'off';
            app.VoltageTransientTab = uitab(app.Tab);
            app.VoltageTransientTab.Title = 'Voltage Transient';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Electrode_Analyser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElectrodeAnalyser)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElectrodeAnalyser)
        end
    end
end